classdef Post_processing_GUI_CNMFE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        ImportdataMenu             matlab.ui.container.Menu
        ImportvideoMenu            matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        ImageSummaryPanel          matlab.ui.container.Panel
        MainAxes1                  matlab.ui.control.UIAxes
        MainAxes2                  matlab.ui.control.UIAxes
        SelectionModeButtonGroup   matlab.ui.container.ButtonGroup
        AutomaticButton            matlab.ui.control.RadioButton
        ManualButton               matlab.ui.control.RadioButton
        SelectRegionButton         matlab.ui.control.Button
        RefreshButton              matlab.ui.control.Button
        ContrastSliderLabel        matlab.ui.control.Label
        ContrastSlider             matlab.ui.control.Slider
        OverlaySliderLabel         matlab.ui.control.Label
        OverlaySlider              matlab.ui.control.Slider
        BoundaryEditFieldLabel     matlab.ui.control.Label
        BoundaryEditField          matlab.ui.control.NumericEditField
        LabelProcessedNeuron       matlab.ui.control.Label
        LabelTotalNeuron           matlab.ui.control.Label
        CalciumSignalsPanel        matlab.ui.container.Panel
        ViewButton                 matlab.ui.control.Button
        AcceptButton               matlab.ui.control.Button
        DeleteButton               matlab.ui.control.Button
        AvailablePoolListBoxLabel  matlab.ui.control.Label
        AvailablePoolListBox       matlab.ui.control.ListBox
        DeletedPoolListBoxLabel    matlab.ui.control.Label
        DeletedPoolListBox         matlab.ui.control.ListBox
        AcceptedPoolListBoxLabel   matlab.ui.control.Label
        AcceptedPoolListBox        matlab.ui.control.ListBox
        RecoverDeletedButton       matlab.ui.control.Button
        RecoverAcceptedButton      matlab.ui.control.Button
        VideoPanel                 matlab.ui.container.Panel
        SubAxes1                   matlab.ui.control.UIAxes
        SubAxes2                   matlab.ui.control.UIAxes
        Sliderleft                 matlab.ui.control.Slider
        Sliderright                matlab.ui.control.Slider
        SubAxes3                   matlab.ui.control.UIAxes
        DisplayButton              matlab.ui.control.Button
        Slidervideo                matlab.ui.control.Slider
        NextButton                 matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        PlayButton                 matlab.ui.control.StateButton
        SpeedSpinnerLabel          matlab.ui.control.Label
        SpeedSpinner               matlab.ui.control.Spinner
        Button                     matlab.ui.control.Button
        SubmitButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        pathname
        rawdata % imported rawdata
        imax_border
        acceptedPool
        deletePool 
        processedPool
        numF=10; %number of rois to process in each time 
        id
        idsorted
        currentimg
        pos_border
        pos_rect
        trace
        fullroiborder
        MainAxes1text
        tracetext
        coloralpha=0.3
        img1
        img2
        img2shown
        roi
        sig
        xs
        ys
        lineleft
        lineright
        linevideo
        videoreg
        videoraw
        selectedid
        ims1
        ims2
        bh2
        bh3
        htrace
        idacceptedraw
    end
    
    methods (Access = public)
            
        function id = autoROIselect(app)
            numcellremaining= size(app.rawdata.sigfn,1)-length(app.processedPool);
            if numcellremaining<app.numF
                app.numF=numcellremaining;
                disp('Less than 10 cells remaining');
            end
            sigfn_p=app.rawdata.sigfn;
            seeds_p=app.rawdata.seedsfn;
            roifnr_p=app.rawdata.roifnr;
            
            if ~isempty(app.processedPool)
                sigfn_p(app.processedPool,:)=zeros(length(app.processedPool),size(app.rawdata.sigfn,2));
                seeds_p(app.processedPool)=zeros(length(app.processedPool),1);
            end
            [~,maxID]=sort(max(sigfn_p,[],2),'descend'); %sigfn - ROIs already censored
            poolID=maxID(1:app.numF); %the 10 strongest signal
            %poolSeed=seeds(poolID);
            [app.ys,app.xs] = ind2sub([app.rawdata.ms.height, app.rawdata.pixw], seeds_p);
            centres=[app.ys(poolID) app.xs(poolID)];
            idx=knnsearch([app.ys,app.xs],centres,'K',app.numF);
            %Next find the one with largest area
            areaF=zeros(app.numF,1);
            coordf=zeros(app.numF,4); %store ymin ymax xmin xmax
            for n=1:app.numF
                fy=[];fx=[];
                for r=1:app.numF
                    roitemp=reshape(roifnr_p(:,idx(n,r)),app.rawdata.ms.height,app.rawdata.pixw);
                    [fyt,fxt]=find(roitemp~=0);
                    fy=[fy;fyt];
                    fx=[fx;fxt];
                end
                fy1=min(fy);fy2=max(fy);
                fx1=min(fx);fx2=max(fx);
                areaF(n)=(fy2-fy1)*(fx2-fx1);
                coordf(n,:)=[fy1 fy2 fx1 fx2];
            end
            [~,winnerind]=min(areaF);
            %winnerID=poolID(winnerind);
            app.pos_rect=[coordf(winnerind,3) coordf(winnerind,1) ...
                coordf(winnerind,4)-coordf(winnerind,3) ...
                coordf(winnerind,2)-coordf(winnerind,1)];
            app.pos_border=coordf(winnerind,:);
            hold(app.MainAxes1,'on')
            hold(app.MainAxes2,'on')
            rectangle('Position',app.pos_rect,'EdgeColor','g','Parent',app.MainAxes1);
            rectangle('Position',app.pos_rect,'EdgeColor','g','Parent',app.MainAxes2);
            id=idx(winnerind,:);
            
        end
            
        function id = manualROIselect(app)
            
            [app.ys,app.xs] = ind2sub([app.rawdata.ms.height, app.rawdata.pixw], app.rawdata.seedsfn);
            tempfig=figure('units','pixels',...
                    'position',[100 300 800 600],...
                    'menubar','none',...
                    'name','ROI selection window');
            imshow(app.currentimg)
            h_rect = drawrectangle();
            app.pos_rect = round(h_rect.Position);
            x1=app.pos_rect(1);x2=app.pos_rect(1)+app.pos_rect(3);
            y1=app.pos_rect(2);y2=app.pos_rect(2)+app.pos_rect(4);            
            id=find(app.ys>=y1 & app.ys<=y2 & app.xs>=x1 & app.xs<=x2);
            id=id';
            [app.pos_border,app.pos_rect]=ROIborder(app,id);
            hold(app.MainAxes1,'on')
            hold(app.MainAxes2,'on')
            rectangle('Position',app.pos_rect,'EdgeColor','g','Parent',app.MainAxes1);
            rectangle('Position',app.pos_rect,'EdgeColor','g','Parent',app.MainAxes2);
            close(tempfig)

            
        end
        
        function img_rgb = ImageOverlay(app,img,roi,idview,iddelete,idaccept)
            img_rgb=cat(3,img,img,img);
            roi=reshape(roi,app.rawdata.ms.height,app.rawdata.pixw,[]);
            iddelete=[iddelete, app.deletePool];
            idaccept=[idaccept,app.acceptedPool];
            ids={iddelete,idaccept,idview};
            for i=1:length(ids)
                if ~isempty(ids{i})
                    idtemp=ids{i};
                    for j=1:length(ids{i})               
                        roitempp=roi(:,:,idtemp(j));
                        roitempp((roitempp<=(1- app.BoundaryEditField.Value/100)*max(roitempp,[],'all')))=0;
                        img_rgb(:,:,i)=img_rgb(:,:,i)+roitempp*app.coloralpha;
                    end
                end
            end
            
                       
        end
        
        function update_all(app)
            
            idselected=cellfun(@str2num,app.AvailablePoolListBox.Value);
            iddeleted=cellfun(@str2num,app.DeletedPoolListBox.Items);
            idaccepted=cellfun(@str2num,app.AcceptedPoolListBox.Items);

            %color the selected trace 
            for i=1:length(app.idsorted)
                if ismember(app.idsorted(i),idselected)
                    set(app.tracetext(i),'color','blue');
                    set(app.trace(i),'color','blue');
                    set(app.MainAxes1text(i),'color','blue');
                elseif ismember(app.idsorted(i),iddeleted)
                    set(app.tracetext(i),'color','red');
                    set(app.trace(i),'color','red');
                    set(app.MainAxes1text(i),'color','red');
                elseif ismember(app.idsorted(i),idaccepted)
                    set(app.tracetext(i),'color','green');
                    set(app.trace(i),'color','green');
                    set(app.MainAxes1text(i),'color','green');
                else
                    set(app.tracetext(i),'color',[0.4 0.4 0.4]);
                    set(app.trace(i),'color',[0.4 0.4 0.4]);
                    set(app.MainAxes1text(i),'color','cyan');
                end                
            end
            
            img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,idselected,iddeleted,idaccepted);
            app.img2shown.CData=img2temp;
            
            if ~isempty(idselected)
                idflipped=fliplr(idselected);
                sigt=app.rawdata.sigfn(idflipped,:);
                Boundary=cell(size(sigt, 1),1);
                for i=1:length(idflipped)
                    sigt(i,:)=normalize(sigt(i,:),'range');
                    roitempp=app.roi(:,idflipped(i));
                    roitempp((roitempp<=(1- app.BoundaryEditField.Value/100)*max(roitempp,[],'all')))=0;
                    roitempp=reshape(roitempp,app.rawdata.ms.height,app.rawdata.pixw);
                    B=bwboundaries(roitempp);
                    Boundary{i}=B{1};
                end
                
                imh0=plot(app.SubAxes1,(sigt+(1:size(sigt,1))')');
                app.SubAxes1.XLim=[1,size(sigt,2)];
                hold(app.SubAxes1,'on');
                app.Sliderleft.Limits=[1 size(sigt,2)];
                app.Sliderleft.Value=1;            
                app.Sliderright.Limits=[1 size(sigt,2)];
                app.Sliderright.Value=size(sigt,2);      
                app.Slidervideo.Limits=[1 size(sigt,2)];
                app.Slidervideo.Value=1;
                app.SubAxes1.XTick=[0 size(sigt,2)];
                app.lineleft=plot(app.SubAxes1, [app.Sliderleft.Value app.Sliderleft.Value],app.SubAxes1.YLim,'Color','g');
                app.lineright=plot(app.SubAxes1, [app.Sliderright.Value app.Sliderright.Value],app.SubAxes1.YLim,'Color','g');
                app.linevideo=plot(app.SubAxes1, [app.Sliderleft.Value app.Sliderleft.Value],app.SubAxes1.YLim,'--b');
                app.selectedid=idselected;
                
                %plot images
                app.ims1=imshow(app.img1,'Parent',app.SubAxes2);
                app.ims2=imshow(app.img2/app.ContrastSlider.Value,'Parent',app.SubAxes3);
                hold(app.SubAxes2,'on');
                hold(app.SubAxes3,'on');
                for k=1:length(idflipped)
                    app.bh2(k)=plot(app.SubAxes2,Boundary{k}(:,2),Boundary{k}(:,1),'g','LineWidth',1,'Color',imh0(k).Color);
                    app.bh3(k)=plot(app.SubAxes3,Boundary{k}(:,2),Boundary{k}(:,1),'g','LineWidth',1,'Color',imh0(k).Color);
                    text(app.SubAxes1,size(sigt,2),(k+1)-0.5, ['#' num2str(idflipped(k))],'FontSize',8,'Color',imh0(k).Color);
                    text(app.SubAxes2,app.xs(idflipped(k)),app.ys(idflipped(k)),num2str(idflipped(k)),'Color',imh0(k).Color);
                    text(app.SubAxes3,app.xs(idflipped(k)),app.ys(idflipped(k)),num2str(idflipped(k)),'Color',imh0(k).Color);
                end
            end
            
        end
        
        function [border,rectPos]=ROIborder(app,id)
            roir=app.rawdata.roifnr;
            pixh=app.rawdata.ms.height;
            pixw=app.rawdata.pixw;
            ROI=roir(:,id);
            by=[];bx=[];
            for n=1:size(ROI,2)
                roitemp=reshape(ROI(:,n),pixh,pixw);
                [byt,bxt]=find(roitemp~=0);
                by=[by; byt];
                bx=[bx; bxt];
            end
            by1=min(by);by2=max(by);
            bx1=min(bx);bx2=max(bx);
            border=[by1,by2, bx1, bx2]; 
            rectPos=[bx1,by1,bx2-bx1,by2-by1];       %x y w h
        end
    end
    

    methods (Access = private)

        % Callback function
        function MenuSelected(app, event)
          
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            try
                idselected=cellfun(@str2num,app.AvailablePoolListBox.Value);
                iddeleted=cellfun(@str2num,app.DeletedPoolListBox.Items);
                idaccepted=cellfun(@str2num,app.AcceptedPoolListBox.Items);
                img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,idselected,iddeleted,idaccepted);
                app.img2shown.CData=img2temp;   
            catch
                img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,[],app.deletePool,app.acceptedPool);
                app.img2shown.CData=img2temp;
            end
            
        end

        % Selection changed function: SelectionModeButtonGroup
        function SelectionModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectionModeButtonGroup.SelectedObject;
            switch selectedButton
                case app.AutomaticButton
                    disp('Automatic mode selected')
                    
                case app.ManualButton
                    disp('Manual mode selected')
            end
        end

        % Button pushed function: SelectRegionButton
        function SelectRegionButtonPushed(app, event)
            
            app.RefreshButton.Enable = 'on';
            app.SelectRegionButton.Enable = 'off';
            app.CalciumSignalsPanel.Visible = 'on';
            selectedButton = app.SelectionModeButtonGroup.SelectedObject;
            switch selectedButton
                case app.AutomaticButton
                    app.id=autoROIselect(app);
                    disp(['Selected ID: ' num2str(app.id)])
                case app.ManualButton
                    app.id=manualROIselect(app);
                    disp(['Selected ID: ' num2str(app.id)])
            end
            
            % Focus reference image 
            app.MainAxes1.YLim=app.pos_border(1:2);
            app.MainAxes1.XLim=app.pos_border(3:4);
            app.MainAxes2.YLim=app.pos_border(1:2);
            app.MainAxes2.XLim=app.pos_border(3:4);

            
            % Plot on signal panel
            maxamp=max(app.rawdata.sigfn(app.id,:),[],2);
            [~,sortedind]=sort(maxamp);
            sortedID=app.id(sortedind);
            
            for j=1:length(sortedID)
                app.MainAxes1text(j)=text(app.MainAxes1,app.xs(sortedID(j)), app.ys(sortedID(j)), ...
                    num2str(sortedID(j)),'Color','cyan');
            end

            app.idsorted=sortedID;
            time=(1:1:size(app.rawdata.sigfn,2))/10;
            sigp1=app.rawdata.sigfn;
            sigmax=[];
            for j=1:length(sortedID)
                h(j)=subplot(length(sortedID),1,j,'Parent',app.CalciumSignalsPanel);
                app.trace(j)=plot(h(j),time,sigp1(sortedID(j), :), 'color', [0.5,0.5,0.5]);
                h(j).XTick = [];
                h(j).YTick = [];
                h(j).XColor = [0.9412 0.9412 0.9412];
                h(j).YColor = [0.9412 0.9412 0.9412];
                h(j).LineWidth = 0.1;
                h(j).Color = 'none';
                sigmax=[sigmax, max(h(j).YLim)];
            end
            
            app.tracetext=zeros(length(sortedID),1);
            for j=1:length(sortedID)
                h(j).YLim=[0 max(sigmax)];
                xlims=h(j).XLim;
                app.tracetext(j)=text(h(j),xlims(2),max(sigmax)/2, ['#' num2str(sortedID(j))],'FontSize',8,'Color',[0.4 0.4 0.4]);
            end
            
            text(h(end),0,max(sigmax),num2str(max(sigmax)),'FontSize',8,'Color','k');
            
            % Fill the lists
            delID=sortedID(ismember(sortedID,app.deletePool));
            for jj=1:length(delID)
                delID_ca{1,jj}= num2str(delID(jj));
                app.DeletedPoolListBox.Items=delID_ca;
            end
            if isempty(delID)
                delID_ca=[];
            end
            accptID=sortedID(ismember(sortedID,app.acceptedPool));
            for jj=1:length(accptID)
                accptID_ca{1,jj}= num2str(accptID(jj));
                app.AcceptedPoolListBox.Items=accptID_ca;
            end
            if isempty(accptID)
                accptID_ca=[];
            end
            nullID=sortedID(~ismember(sortedID,app.deletePool)&~ismember(sortedID,app.acceptedPool));
            for jj=1:length(nullID)
                nullID_ca{1,jj}= num2str(nullID(jj));
                app.AvailablePoolListBox.Items=nullID_ca;
            end
            if isempty(nullID)
                nullID_ca=[];
            end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
            imshow(app.currentimg,'Parent',app.MainAxes1)
            imshow(app.img2/app.ContrastSlider.Value,'Parent',app.MainAxes2);
            app.MainAxes1.XLim = [app.fullroiborder(1) app.fullroiborder(2)];
            app.MainAxes1.YLim = [app.fullroiborder(3) app.fullroiborder(4)];
            app.MainAxes2.XLim = [app.fullroiborder(1) app.fullroiborder(2)];
            app.MainAxes2.YLim = [app.fullroiborder(3) app.fullroiborder(4)];
            
            app.SelectRegionButton.Enable = 'on';
            app.AvailablePoolListBox.Items={};
            app.DeletedPoolListBox.Items={};
            app.AcceptedPoolListBox.Items={};
            app.CalciumSignalsPanel.Visible = 'off';
            app.RefreshButton.Enable='off';
            
        end

        % Menu selected function: ImportdataMenu
        function ImportdataMenuSelected(app, event)
            warning off
            [file,path] = uigetfile('*.mat');
            app.SelectRegionButton.Enable = 'on';
            
            if isequal(file,0)
                %disp('User selected Cancel');   
                path='E:\Haoshan\Miniscope\191126\20190905\Results';
                app.rawdata=load('E:\Haoshan\Miniscope\191126\20190905\Results\ms.mat');
                try
                    app.videoreg=VideoReader(fullfile(path,'ms_mc.avi'));
                    app.videoraw=VideoReader(fullfile(path,'msCam_concat.avi'));
                catch
                    disp('No video file found');
                    app.DisplayButton.Enable='off';
                    app.Slidervideo.Enable='off';
                    app.NextButton.Enable='off';
                    app.PreviousButton.Enable='off';
                    
                end
                path='E:\miniscope\Data\192501_aCA3_VR\04272019';
            else
                %disp(['User selected ', fullfile(path,file)]);
                app.rawdata=load(fullfile(path,file));       
                try
                    %app.videoreg=VideoReader(fullfile(path,'ms_mc.avi'));
                    app.videoreg=VideoReader(fullfile(path,'msCam_concat.avi'));
                catch
                    disp('No video file found');
                    disp('No video file found');
                    app.DisplayButton.Enable='off';
                    app.Slidervideo.Enable='off';
                    app.NextButton.Enable='off';
                    app.PreviousButton.Enable='off';
                end
            end
            
            app.pathname=path;
            
            try
                app.acceptedPool=app.rawdata.acceptedPool;
                app.deletePool=app.rawdata.deletePool;
            catch
                app.acceptedPool=[];
                app.deletePool=[];
            end            
            
            app.rawdata.ms.idx_deleted=[];
            app.rawdata.ms.idx_accepted=0:size(app.rawdata.ms.SFP,3)-1;
            app.processedPool=[app.deletePool;app.acceptedPool];
            app.rawdata.pixw=app.rawdata.ms.width;
            app.rawdata.pixh=app.rawdata.ms.height;
            
            app.img2=app.rawdata.ms.PNR;
            idaccept=app.rawdata.ms.idx_accepted+1;
            roifn=reshape(app.rawdata.ms.SFP,[],size(app.rawdata.ms.SFP,3));
            roifn=roifn(:,idaccept);
            sigraw=app.rawdata.ms.sigraw';
            sigraw=sigraw(idaccept,:);
            app.roi=roifn;
            app.sig=sigraw; 
            app.rawdata.sigfn=app.sig;
            app.rawdata.roifn=app.roi;
            app.LabelProcessedNeuron.Text=num2str(length(app.processedPool));
            app.LabelTotalNeuron.Text=['/   ' num2str(size(app.rawdata.roifn,2))];
            by=[];
            bx=[];
            app.rawdata.imax=max(app.rawdata.ms.SFP(:,:,idaccept),[],3);
            app.rawdata.imax=app.rawdata.imax/(max(app.rawdata.imax(:)));
            app.rawdata.roifnr=app.roi;
            for m=1:size(app.rawdata.roifn,2)
                roitemp=reshape(app.roi(:,m),app.rawdata.ms.height,app.rawdata.ms.width);
                [byt,bxt]=find(roitemp~=0);
                by=[by; byt];
                bx=[bx; bxt];
            end
            app.img1=app.rawdata.imax;
            imaxrgb=cat(3, app.rawdata.imax,app.rawdata.imax,app.rawdata.imax);
            for i=1:length(app.deletePool)
                imaxrgb(:,:,1)=imaxrgb(:,:,1)+reshape(app.rawdata.roifnr(:,app.deletePool(i)),app.rawdata.ms.height,app.rawdata.pixw)*app.coloralpha;
            end
            
            for i=1:length(app.acceptedPool)
                imaxrgb(:,:,2)=imaxrgb(:,:,2)+reshape(app.rawdata.roifnr(:,app.acceptedPool(i)),app.rawdata.ms.height,app.rawdata.pixw)*app.coloralpha;
            end
            by1=min(by);by2=max(by);
            bx1=min(bx);bx2=max(bx);
            app.currentimg=imaxrgb;
            app.fullroiborder=[bx1,bx2,by1,by2];
            imshow(imaxrgb,'Parent',app.MainAxes1);
            app.MainAxes1.XLim = [bx1 bx2];
            app.MainAxes1.YLim = [by1 by2];
            app.rawdata.imaxn=app.rawdata.imax;
            app.img2shown=imshow(app.img2/app.ContrastSlider.Value,'Parent',app.MainAxes2);
            app.MainAxes2.XLim = [bx1 bx2];
            app.MainAxes2.YLim = [by1 by2];
            roitempp=app.rawdata.ms.SFP(:,:,idaccept);
            app.rawdata.seedsfn=zeros(size(roitempp,3),1);
            for i=1:size(roitempp,3)
                temp=reshape(roitempp(:,:,i),1,[]);
                [~,app.rawdata.seedsfn(i)]=max(temp);
            end
            app.idacceptedraw=idaccept;
        end

        % Callback function
        function SelectButtonPushed(app, event)
            
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            idselected=cellfun(@str2num,app.AvailablePoolListBox.Value);
            iddeleted=cellfun(@str2num,app.DeletedPoolListBox.Items);
            idaccepted=cellfun(@str2num,app.AcceptedPoolListBox.Items);
            app.VideoPanel.Visible='on';
            %to erase previous plots
            hold(app.SubAxes1,'off');
            hold(app.SubAxes2,'off');
            hold(app.SubAxes3,'off');
            
            %color the selected trace 
            for i=1:length(app.idsorted)
                if ismember(app.idsorted(i),idselected)
                    set(app.tracetext(i),'color','blue');
                    set(app.trace(i),'color','blue');
                    set(app.MainAxes1text(i),'color','blue');
                elseif ismember(app.idsorted(i),iddeleted)
                    set(app.tracetext(i),'color','red');
                    set(app.trace(i),'color','red');
                    set(app.MainAxes1text(i),'color','red');
                elseif ismember(app.idsorted(i),idaccepted)
                    set(app.tracetext(i),'color','green');
                    set(app.trace(i),'color','green');
                    set(app.MainAxes1text(i),'color','green');
                else
                    set(app.tracetext(i),'color',[0.4 0.4 0.4]);
                    set(app.trace(i),'color',[0.4 0.4 0.4]);
                    set(app.MainAxes1text(i),'color','cyan');
                end                
            end
            
            img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,idselected,iddeleted,idaccepted);
            app.img2shown.CData=img2temp;

            idflipped=fliplr(idselected);
            sigt=app.rawdata.sigfn(idflipped,:);
            Boundary=cell(size(sigt, 1),1);
            for i=1:length(idflipped)
                sigt(i,:)=sigt(i,:)/max(sigt(i,:));
                roitempp=app.roi(:,idflipped(i));
                roitempp((roitempp<=(1- app.BoundaryEditField.Value/100)*max(roitempp,[],'all')))=0;
                roitempp=reshape(roitempp,app.rawdata.ms.height,app.rawdata.pixw);
                B=bwboundaries(roitempp);
                Boundary{i}=B{1};
            end
            
            imh0=plot(app.SubAxes1,(sigt+(1:size(sigt,1))')');
            app.SubAxes1.XLim=[1,size(sigt,2)];
            hold(app.SubAxes1,'on');
            app.Sliderleft.Limits=[1 size(sigt,2)];
            app.Sliderleft.Value=1;            
            app.Sliderright.Limits=[1 size(sigt,2)];
            app.Sliderright.Value=size(sigt,2);      
            app.Slidervideo.Limits=[1 size(sigt,2)];
            app.Slidervideo.Value=1;
            app.SubAxes1.XTick=[0 size(sigt,2)];
            app.lineleft=plot(app.SubAxes1, [app.Sliderleft.Value app.Sliderleft.Value],app.SubAxes1.YLim,'Color','g');
            app.lineright=plot(app.SubAxes1, [app.Sliderright.Value app.Sliderright.Value],app.SubAxes1.YLim,'Color','g');
            app.linevideo=plot(app.SubAxes1, [app.Sliderleft.Value app.Sliderleft.Value],app.SubAxes1.YLim,'--b');
            app.selectedid=idselected;
            
            %plot images
            app.videoreg.CurrentTime=1;
            
            app.ims1=imagesc(readFrame(app.videoreg),'Parent',app.SubAxes2);
            app.ims2=imagesc(reshape(app.roi(:,idselected)*app.sig(idselected,1),app.rawdata.ms.height,app.rawdata.ms.width),'Parent',app.SubAxes3);
            c=colormap('gray');
            app.SubAxes2.Colormap=c;
            app.SubAxes3.Colormap=c;
%             app.ims1=imshow(readFrame(app.videoreg),[0,0.8],'Parent',app.SubAxes2);
%             app.ims2=imshow(reshape(app.roi(:,idselected)*app.sig(idselected,1),app.rawdata.ms.height,app.rawdata.ms.width),'Parent',app.SubAxes3);
            hold(app.SubAxes2,'on');
            hold(app.SubAxes3,'on');
            for k=1:length(idflipped)
                app.bh2(k)=plot(app.SubAxes2,Boundary{k}(:,2),Boundary{k}(:,1),'g','LineWidth',1,'Color',imh0(k).Color);
                app.bh3(k)=plot(app.SubAxes3,Boundary{k}(:,2),Boundary{k}(:,1),'g','LineWidth',1,'Color',imh0(k).Color);
                text(app.SubAxes1,size(sigt,2),(k+1)-0.5, ['#' num2str(idflipped(k))],'FontSize',8,'Color',imh0(k).Color);
                text(app.SubAxes2,app.xs(idflipped(k)),app.ys(idflipped(k)),num2str(idflipped(k)),'Color',imh0(k).Color);
                text(app.SubAxes3,app.xs(idflipped(k)),app.ys(idflipped(k)),num2str(idflipped(k)),'Color',imh0(k).Color);
            end
            app.SubAxes2.YLim=app.pos_border(1:2);
            app.SubAxes2.XLim=app.pos_border(3:4);
            app.SubAxes3.YLim=app.pos_border(1:2);
            app.SubAxes3.XLim=app.pos_border(3:4);
            
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            %update the lists
            app.AcceptedPoolListBox.Items=[app.AcceptedPoolListBox.Items,app.AvailablePoolListBox.Value]; %move selected to the new list
            idselected=cellfun(@str2num,app.AvailablePoolListBox.Value);
            idavailable=cellfun(@str2num,app.AvailablePoolListBox.Items);
            indselected=find(ismember(idavailable,idselected)); 
            currentlist=app.AvailablePoolListBox.Items;            
            currentlist(indselected)=[];
            app.AvailablePoolListBox.Items=currentlist;
            app.RecoverAcceptedButton.Enable='on';
            app.AvailablePoolListBox.Value={};
            
            idaccepted=cellfun(@str2num,app.AcceptedPoolListBox.Items);
            if ~isempty(idaccepted)
                indtemp=find(ismember(app.idsorted,idaccepted));
                for j=1:length(indtemp)
                    set(app.tracetext(indtemp(j)),'color','green');
                    set(app.trace(indtemp(j)),'color','green');
                    set(app.MainAxes1text(indtemp(j)),'color','green');
                end
            end
            iddeleted=cellfun(@str2num,app.DeletedPoolListBox.Items);
            img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,[],iddeleted,idaccepted);
            app.img2shown.CData=img2temp;
            app.SubAxes1.Visible='off';
            app.SubAxes2.Visible='off';
            app.SubAxes3.Visible='off';


            app.SubmitButton.Enable='on';
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            %update the lists
            app.DeletedPoolListBox.Items=[app.DeletedPoolListBox.Items,app.AvailablePoolListBox.Value]; %move selected to the new list
            idselected=cellfun(@str2num,app.AvailablePoolListBox.Value);
            idavailable=cellfun(@str2num,app.AvailablePoolListBox.Items);
            indselected=find(ismember(idavailable,idselected)); 
            currentlist=app.AvailablePoolListBox.Items;            
            currentlist(indselected)=[]; 
            app.AvailablePoolListBox.Items=currentlist;
            app.RecoverDeletedButton.Enable='on';
            app.AvailablePoolListBox.Value={};
            
            iddeleted=cellfun(@str2num,app.DeletedPoolListBox.Items);
            if ~isempty(iddeleted)
                indtemp=find(ismember(app.idsorted,iddeleted));
                for j=1:length(indtemp)
                    set(app.tracetext(indtemp(j)),'color','red');
                    set(app.trace(indtemp(j)),'color','red');
                    set(app.MainAxes1text(indtemp(j)),'color','red');
                end
            end
            idaccepted=cellfun(@str2num,app.AcceptedPoolListBox.Items);
            img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,[],iddeleted,idaccepted);
            app.img2shown.CData=img2temp;
            app.SubAxes1.Visible='off';
            app.SubAxes2.Visible='off';
            app.SubAxes3.Visible='off';

            app.SubmitButton.Enable='on';
        end

        % Button pushed function: RecoverDeletedButton
        function RecoverDeletedButtonPushed(app, event)
            %remove the elements from the list             
            elementselected=app.DeletedPoolListBox.Value;
            currentlist=app.DeletedPoolListBox.Items;
            currentlist(ismember(currentlist,elementselected))=[];
            app.DeletedPoolListBox.Items=currentlist;
            
            %put the elements back to the available pool 
            deletedlist=app.DeletedPoolListBox.Items;
            acceptedlist=app.AcceptedPoolListBox.Items;
            idna=[cellfun(@str2num,deletedlist),cellfun(@str2num,acceptedlist)];
            fulllist=app.idsorted;
            fulllist(ismember(app.idsorted,idna))=[];
            newlist=cell(length(fulllist),1);
            for i=1:length(fulllist)
                newlist{i}=num2str(fulllist(i));
            end 
            app.AvailablePoolListBox.Items=newlist;
            if isempty(app.DeletedPoolListBox.Items)
                app.RecoverDeletedButton.Enable='off';
            end
            
            update_all(app)
            
        end

        % Button pushed function: RecoverAcceptedButton
        function RecoverAcceptedButtonPushed(app, event)
            %remove the elements from the list 
            elementselected=app.AcceptedPoolListBox.Value;
            currentlist=app.AcceptedPoolListBox.Items;
            currentlist(ismember(currentlist,elementselected))=[];
            app.AcceptedPoolListBox.Items=currentlist;
            
            %put the elements back to the available pool 
            deletedlist=app.DeletedPoolListBox.Items;
            acceptedlist=app.AcceptedPoolListBox.Items;
            idna=[cellfun(@str2num,deletedlist),cellfun(@str2num,acceptedlist)];
            fulllist=app.idsorted;
            fulllist(ismember(app.idsorted,idna))=[];
            newlist=cell(length(fulllist),1);
            for i=1:length(fulllist)
                newlist{i}=num2str(fulllist(i));
            end 
            app.AvailablePoolListBox.Items=newlist;
            if isempty(app.AcceptedPoolListBox)
                app.RecoverAcceptedButton.Enable='off';
            end 
            
            update_all(app)
        end

        % Value changed function: OverlaySlider
        function OverlaySliderValueChanged(app, event)
            app.coloralpha = app.OverlaySlider.Value;
            try
                idselected=cellfun(@str2num,app.AvailablePoolListBox.Value);
                iddeleted=cellfun(@str2num,app.DeletedPoolListBox.Items);
                idaccepted=cellfun(@str2num,app.AcceptedPoolListBox.Items);
                img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,idselected,iddeleted,idaccepted);
                app.img2shown.CData=img2temp;
            catch
                img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,[],app.deletePool,app.acceptedPool);
                app.img2shown.CData=img2temp;
            end
        end

        % Value changed function: Sliderleft
        function SliderleftValueChanged(app, event)
            value = floor(app.Sliderleft.Value);
            xlimtemp=app.SubAxes1.XLim;             
            try
                app.SubAxes1.XLim=[value,xlimtemp(2)];
                app.SubAxes1.XTick=app.SubAxes1.XLim;
                app.Slidervideo.Limits=[floor(app.Sliderleft.Value),ceil(app.Sliderright.Value)];
            catch
                disp('Please slide it back')
            end
            
            app.Slidervideo.Value=value;
            app.linevideo.XData=[value, value];
        end

        % Value changing function: Sliderleft
        function SliderleftValueChanging(app, event)
            changingValue = event.Value;
            set(app.lineleft,'XData',[changingValue changingValue]);       
            app.Sliderright.MajorTicks=floor(changingValue);
            
        end

        % Value changed function: Sliderright
        function SliderrightValueChanged(app, event)
            value = ceil(app.Sliderright.Value);
            xlimtemp=app.SubAxes1.XLim;
              
            try
                app.SubAxes1.XLim=[xlimtemp(1),value];
                app.SubAxes1.XTick=app.SubAxes1.XLim;
                app.Slidervideo.Limits=[floor(app.Sliderleft.Value),ceil(app.Sliderright.Value)];
            catch
                disp('Please slide it back')
            end
        end

        % Value changing function: Sliderright
        function SliderrightValueChanging(app, event)
            changingValue = event.Value;
            set(app.lineright,'XData',[changingValue changingValue]);
            app.Sliderright.MajorTicks=ceil(changingValue);
        end

        % Menu selected function: ImportvideoMenu
        function ImportvideoMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            addpath('myFun\')
            app.SelectRegionButton.Enable = 'on';
            if isequal(file,0)
                %disp('User selected Cancel');   
                app.videoreg=matfile('E:\miniscope\Data\192501_aCA3_VR\04272019\msCam_reg.mat');
            else
                %disp(['User selected ', fullfile(path,file)]);
                app.videoreg=matfile(fullfile(path,file));               
            end
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            frameno=round(app.Slidervideo.Value);            
            app.ims1.CData=app.videoreg.reg(:,:,frameno);
            app.ims2.CData=app.videoraw.frame_all(:,:,frameno);
        end

        % Value changed function: Slidervideo
        function SlidervideoValueChanged(app, event)
            value = app.Slidervideo.Value;
            app.linevideo.XData=[value, value];
        end

        % Value changing function: Slidervideo
        function SlidervideoValueChanging(app, event)
            frameno = round(event.Value);
            app.linevideo.XData=[frameno, frameno];
            %app.ims1.CData=app.videoreg.reg(:,:,frameno);
            idt=cellfun(@str2num,app.AvailablePoolListBox.Value);
            img=reshape(app.roi(:,idt)*app.sig(idt,frameno),app.rawdata.ms.height,app.rawdata.ms.width);
            app.ims2.CData=img;
            app.videoreg.CurrentTime=round((1/app.videoreg.FrameRate),5)*(frameno-1);
            app.ims1.CData=readFrame(app.videoreg);
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.Slidervideo.Value=app.Slidervideo.Value+app.SpeedSpinner.Value;
            frameno=round(app.Slidervideo.Value);
            app.linevideo.XData=[frameno, frameno];
            idt=cellfun(@str2num,app.AvailablePoolListBox.Value);
            img=reshape(app.roi(:,idt)*app.sig(idt,frameno),app.rawdata.ms.height,app.rawdata.ms.width);
            app.ims2.CData=img;
            app.videoreg.CurrentTime=round((1/app.videoreg.FrameRate),5)*(frameno-1);
            app.ims1.CData=readFrame(app.videoreg);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.Slidervideo.Value=app.Slidervideo.Value-app.SpeedSpinner.Value;
            frameno=round(app.Slidervideo.Value);
            app.linevideo.XData=[frameno, frameno];
            idt=cellfun(@str2num,app.AvailablePoolListBox.Value);
            img=reshape(app.roi(:,idt)*app.sig(idt,frameno),app.rawdata.ms.height,app.rawdata.ms.width);
            app.ims2.CData=img;
            app.videoreg.CurrentTime=round((1/app.videoreg.FrameRate),5)*(frameno-1);
            app.ims1.CData=readFrame(app.videoreg);
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            while(value==true) 
                if event.Value==false
                    break
                end
                app.Slidervideo.Value=app.Slidervideo.Value+app.SpeedSpinner.Value;
                frameno=round(app.Slidervideo.Value); 
                app.videoreg.CurrentTime=round((1/app.videoreg.FrameRate),5)*(frameno-1);
                if frameno<=app.Sliderright.Value
                    app.linevideo.XData=[frameno, frameno];
                    %idt=cellfun(@str2num,app.AvailablePoolListBox.Value);
                    img=reshape(app.roi(:,:)*app.sig(:,frameno),app.rawdata.ms.height,app.rawdata.ms.width);
                    img(img<0)=0;
                    app.ims2.CData=img;
                    
                    app.ims1.CData=readFrame(app.videoreg);
                    pause(0.1)
                else
                    app.PlayButton.Value=false;
                    break                   
                end
                value=app.PlayButton.Value;
            end
                         
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            value = app.SpeedSpinner.Value;
            
        end

        % Value changed function: BoundaryEditField
        function BoundaryEditFieldValueChanged(app, event)
            idselected=cellfun(@str2num,app.AvailablePoolListBox.Value);
            iddeleted=cellfun(@str2num,app.DeletedPoolListBox.Items);
            idaccepted=cellfun(@str2num,app.AcceptedPoolListBox.Items);
            img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,idselected,iddeleted,idaccepted);
            app.img2shown.CData=img2temp;
            try
                sigt=app.rawdata.sigfn(app.selectedid,:);
                Boundary=cell(size(sigt, 1),1);
                idflipped=fliplr(app.selectedid);
                for i=1:length(idflipped)
                    roitempp=app.roi(:,idflipped(i));
                    roitempp((roitempp<=(1- app.BoundaryEditField.Value/100)*max(roitempp,[],'all')))=0;
                    roitempp=reshape(roitempp,app.rawdata.ms.height,app.rawdata.pixw);
                    B=bwboundaries(roitempp);
                    Boundary{i}=B{1};
                end
                
                for k=1:length(idflipped)
                    set(app.bh2(k),'XData',Boundary{k}(:,2));
                    set(app.bh2(k),'YData',Boundary{k}(:,1));
                    
                    set(app.bh3(k),'XData',Boundary{k}(:,2));
                    set(app.bh3(k),'YData',Boundary{k}(:,1));
    
                end
            catch
                disp('no signal selected')
            end
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            iddeleted=cellfun(@str2num,app.DeletedPoolListBox.Items);
            idaccepted=cellfun(@str2num,app.AcceptedPoolListBox.Items);
            app.acceptedPool=app.acceptedPool(~ismember(app.acceptedPool,iddeleted)); %if the id were accepted previously and now is deleted, we delete it from the accepted pool
            app.deletePool=app.deletePool(~ismember(app.deletePool,idaccepted));
            app.acceptedPool=[app.acceptedPool,idaccepted];
            app.deletePool=[app.deletePool,iddeleted];
            app.processedPool=[app.acceptedPool,app.deletePool];
            
            %update the main axes
            hold(app.MainAxes1,'off');
            hold(app.MainAxes2,'off');
            app.currentimg=ImageOverlay(app,app.img1,app.roi,[],app.deletePool,app.acceptedPool);
            imshow(app.currentimg,'Parent',app.MainAxes1);
            img2temp=ImageOverlay(app,app.img2/app.ContrastSlider.Value,app.roi,[],app.deletePool,app.acceptedPool);
            app.img2shown=imshow(img2temp,'Parent',app.MainAxes2);
            app.MainAxes1.XLim = [app.fullroiborder(1) app.fullroiborder(2)];
            app.MainAxes1.YLim = [app.fullroiborder(3) app.fullroiborder(4)];
            app.MainAxes2.XLim = [app.fullroiborder(1) app.fullroiborder(2)];
            app.MainAxes2.YLim = [app.fullroiborder(3) app.fullroiborder(4)];
            
            %make panels invisible
            app.CalciumSignalsPanel.Visible='off';
            app.VideoPanel.Visible='off';
            
            %clear the lists
            app.AvailablePoolListBox.Items={};
            app.DeletedPoolListBox.Items={};
            app.AcceptedPoolListBox.Items={};
                
            app.SelectRegionButton.Enable='on';
            
            app.LabelProcessedNeuron.Text=num2str(length(app.processedPool));
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            filename = fullfile(app.pathname, 'post_processed.mat');           
            data=app.rawdata;
            deletedPool=app.deletePool;
            acceptedPool=app.acceptedPool;
            save(filename,'-struct','data');
            save(filename,'deletedPool','acceptedPool','-append');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImportdataMenu = uimenu(app.Menu);
            app.ImportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportdataMenuSelected, true);
            app.ImportdataMenu.Text = 'Import data';
            app.ImportvideoMenu = uimenu(app.Menu);
            app.ImportvideoMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportvideoMenuSelected, true);
            app.ImportvideoMenu.Text = 'Import video';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save ';
            app.ImageSummaryPanel = uipanel(app.UIFigure);
            app.ImageSummaryPanel.Title = 'Image Summary';
            app.MainAxes1 = uiaxes(app.ImageSummaryPanel);
            title(app.MainAxes1, 'IMAX')
            xlabel(app.MainAxes1, '')
            ylabel(app.MainAxes1, '')
            app.MainAxes1.XTick = [];
            app.MainAxes1.YTick = [];
            app.MainAxes2 = uiaxes(app.ImageSummaryPanel);
            title(app.MainAxes2, 'IMAXN')
            xlabel(app.MainAxes2, '')
            ylabel(app.MainAxes2, '')
            app.MainAxes2.XTick = [];
            app.MainAxes2.YTick = [];
            app.SelectionModeButtonGroup = uibuttongroup(app.ImageSummaryPanel);
            app.SelectionModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectionModeButtonGroupSelectionChanged, true);
            app.SelectionModeButtonGroup.Title = 'Selection Mode';
            app.AutomaticButton = uiradiobutton(app.SelectionModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.ManualButton = uiradiobutton(app.SelectionModeButtonGroup);
            app.ManualButton.Text = 'Manual ';
            app.SelectRegionButton = uibutton(app.ImageSummaryPanel, 'push');
            app.SelectRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionButtonPushed, true);
            app.SelectRegionButton.Enable = 'off';
            app.SelectRegionButton.Text = 'Select Region';
            app.RefreshButton = uibutton(app.ImageSummaryPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Text = 'Refresh';
            app.ContrastSliderLabel = uilabel(app.ImageSummaryPanel);
            app.ContrastSliderLabel.Text = 'Contrast ';
            app.ContrastSlider = uislider(app.ImageSummaryPanel);
            app.ContrastSlider.Limits = [0 300];
            app.ContrastSlider.MajorTicks = [0 150 300];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 150;
            app.OverlaySliderLabel = uilabel(app.ImageSummaryPanel);
            app.OverlaySliderLabel.Text = 'Overlay';
            app.OverlaySlider = uislider(app.ImageSummaryPanel);
            app.OverlaySlider.Limits = [0 2];
            app.OverlaySlider.MajorTicks = [0 1 2];
            app.OverlaySlider.ValueChangedFcn = createCallbackFcn(app, @OverlaySliderValueChanged, true);
            app.OverlaySlider.Value = 1;
            app.BoundaryEditFieldLabel = uilabel(app.ImageSummaryPanel);
            app.BoundaryEditFieldLabel.Text = 'Boundary%';
            app.BoundaryEditField = uieditfield(app.ImageSummaryPanel, 'numeric');
            app.BoundaryEditField.Limits = [0 100];
            app.BoundaryEditField.RoundFractionalValues = 'on';
            app.BoundaryEditField.ValueChangedFcn = createCallbackFcn(app, @BoundaryEditFieldValueChanged, true);
            app.BoundaryEditField.Value = 60;
            app.LabelProcessedNeuron = uilabel(app.ImageSummaryPanel);
            app.LabelProcessedNeuron.Text = '0';
            app.LabelTotalNeuron = uilabel(app.ImageSummaryPanel);
            app.LabelTotalNeuron.Text = '/';
            app.CalciumSignalsPanel = uipanel(app.UIFigure);
            app.CalciumSignalsPanel.Title = 'Calcium Signals';
            app.CalciumSignalsPanel.Visible = 'off';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AvailablePoolListBoxLabel = uilabel(app.UIFigure);
            app.AvailablePoolListBoxLabel.Text = 'Available Pool ';
            app.AvailablePoolListBox = uilistbox(app.UIFigure);
            app.AvailablePoolListBox.Items = {};
            app.AvailablePoolListBox.Multiselect = 'on';
            app.AvailablePoolListBox.Value = {};
            app.DeletedPoolListBoxLabel = uilabel(app.UIFigure);
            app.DeletedPoolListBoxLabel.Text = 'Deleted Pool ';
            app.DeletedPoolListBox = uilistbox(app.UIFigure);
            app.DeletedPoolListBox.Items = {};
            app.DeletedPoolListBox.Multiselect = 'on';
            app.DeletedPoolListBox.Value = {};
            app.AcceptedPoolListBoxLabel = uilabel(app.UIFigure);
            app.AcceptedPoolListBoxLabel.Text = 'Accepted  Pool ';
            app.AcceptedPoolListBox = uilistbox(app.UIFigure);
            app.AcceptedPoolListBox.Items = {};
            app.AcceptedPoolListBox.Multiselect = 'on';
            app.AcceptedPoolListBox.Value = {};
            app.RecoverDeletedButton = uibutton(app.UIFigure, 'push');
            app.RecoverDeletedButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverDeletedButtonPushed, true);
            app.RecoverDeletedButton.Enable = 'off';
            app.RecoverDeletedButton.Text = 'Recover';
            app.RecoverAcceptedButton = uibutton(app.UIFigure, 'push');
            app.RecoverAcceptedButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverAcceptedButtonPushed, true);
            app.RecoverAcceptedButton.Enable = 'off';
            app.RecoverAcceptedButton.Text = 'Recover';
            app.VideoPanel = uipanel(app.UIFigure);
            app.VideoPanel.Title = 'Video Panel';
            app.SubAxes1 = uiaxes(app.VideoPanel);
            title(app.SubAxes1, 'Selected Signal')
            xlabel(app.SubAxes1, '')
            ylabel(app.SubAxes1, '')
            app.SubAxes1.XTick = [];
            app.SubAxes1.YTick = [];
            app.SubAxes1.Visible = 'off';
            app.SubAxes2 = uiaxes(app.VideoPanel);
            title(app.SubAxes2, 'Motion Corrected')
            xlabel(app.SubAxes2, '')
            ylabel(app.SubAxes2, '')
            app.SubAxes2.XTick = [];
            app.SubAxes2.YTick = [];
            app.SubAxes2.Visible = 'off';
            app.Sliderleft = uislider(app.VideoPanel);
            app.Sliderleft.Limits = [1 100];
            app.Sliderleft.MajorTicks = [];
            app.Sliderleft.ValueChangedFcn = createCallbackFcn(app, @SliderleftValueChanged, true);
            app.Sliderleft.ValueChangingFcn = createCallbackFcn(app, @SliderleftValueChanging, true);
            app.Sliderleft.MinorTicks = [];
            app.Sliderleft.Value = 1;
            app.Sliderright = uislider(app.VideoPanel);
            app.Sliderright.Limits = [1 100];
            app.Sliderright.MajorTicks = [];
            app.Sliderright.ValueChangedFcn = createCallbackFcn(app, @SliderrightValueChanged, true);
            app.Sliderright.ValueChangingFcn = createCallbackFcn(app, @SliderrightValueChanging, true);
            app.Sliderright.MinorTicks = [];
            app.Sliderright.Value = 100;
            app.SubAxes3 = uiaxes(app.VideoPanel);
            title(app.SubAxes3, 'Raw')
            xlabel(app.SubAxes3, '')
            ylabel(app.SubAxes3, '')
            app.SubAxes3.XTick = [];
            app.SubAxes3.YTick = [];
            app.SubAxes3.Visible = 'off';
            app.DisplayButton = uibutton(app.VideoPanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.Slidervideo = uislider(app.VideoPanel);
            app.Slidervideo.Limits = [1 100];
            app.Slidervideo.MajorTicks = [];
            app.Slidervideo.ValueChangedFcn = createCallbackFcn(app, @SlidervideoValueChanged, true);
            app.Slidervideo.ValueChangingFcn = createCallbackFcn(app, @SlidervideoValueChanging, true);
            app.Slidervideo.MinorTicks = [];
            app.Slidervideo.Value = 1;
            app.NextButton = uibutton(app.VideoPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '>';
            app.PreviousButton = uibutton(app.VideoPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '<';
            app.PlayButton = uibutton(app.VideoPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.SpeedSpinnerLabel = uilabel(app.VideoPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.VideoPanel);
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.SpeedSpinner.Value = 1;
            app.Button = uibutton(app.VideoPanel, 'push');
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Text = 'Submit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Post_processing_GUI_CNMFE

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end